classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HeatmapPanel                  matlab.ui.container.Panel
        MisclassifiedPanel            matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        AdvancingBreastCancerDiagnosisAIModelEvaluationDashboardLabel  matlab.ui.control.Label
        AccuracysStatusTextArea       matlab.ui.control.TextArea
        AccuracysStatusTextAreaLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TestButton                    matlab.ui.control.Button
        EvaluateButton                matlab.ui.control.Button
        AIModelNetworkDropDown        matlab.ui.control.DropDown
        AIModelNetworkLabel           matlab.ui.control.Label
        MetricAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ModelMetricMap = containers.Map  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)

   
    disp('Test button clicked'); % For debugging

    % Get selected model from dropdown
    modelName = app.AIModelNetworkDropDown.Value; % 'AlexNet', 'VGG19', 'InceptionNet'

    switch modelName
        case 'AlexNet'
            modelFile = 'AlexNet-ILR-3.mat';
        case 'VGG-19'
            modelFile = 'VGG19-LRDF-3.mat';
        case 'InceptionNet'
            modelFile = 'Inception-ILR-4.mat';
            case 'ResNet-50'
            modelFile = 'resnet50-ILR-1.mat';  % <-- Make sure this .mat file exists
        case 'MobileNetV2'
            modelFile = 'MobileNetV2-ILR-1.mat';
        case 'GoogLeNet'
            modelFile = 'GoogLeNet-ILR-1.mat';
        otherwise
            error('Unknown model selected.');
    end

    testDataPath = fullfile('..', '..', 'Data', 'Common Data', 'ImgDB.mat');
    modelPath = fullfile('..', '..', 'Data', 'Common Data', modelFile);

    load(testDataPath, 'imdsTest');
    load(modelPath, 'netTrained');

    inputSize = netTrained.Layers(1).InputSize;
    augImdsTest = augmentedImageDatastore(inputSize, imdsTest);

    predictedLabels = classify(netTrained, augImdsTest);
    trueLabels = imdsTest.Labels;

    accuracy = mean(predictedLabels == trueLabels);

    app.AccuracysStatusTextArea.Value = sprintf('Test Accuracy for %s: %.2f%%', modelName, accuracy * 100);

    % Plot accuracy
    %app.AccuracyGauge.Value = accuracy * 100;


        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
      
    disp('Evaluate button clicked'); % For debugging

    modelName = app.AIModelNetworkDropDown.Value; % Use correct dropdown name

    
    switch modelName
        case 'AlexNet'
            modelFile = 'AlexNet-ILR-3.mat';
        case 'VGG-19'
            modelFile = 'VGG19-ILR-3.mat';
        case 'InceptionNet'
            modelFile = 'Inception-ILR-4.mat';
        case 'ResNet-50'
            modelFile = 'resnet50-ILR-3.mat';  % <-- Make sure this .mat file exists
        case 'MobileNetV2'
            modelFile = 'MobileNetV2-ILR-3.mat';
        case 'GoogLeNet'
            modelFile = 'GoogLeNet-ILR-3.mat';
        otherwise
            error('Unknown model selected.');
    end

    testDataPath = fullfile('..', '..', 'Data', 'Common Data', 'ImgDB.mat');
    modelPath = fullfile('..', '..', 'Data', 'Common Data', modelFile);

    load(testDataPath, 'imdsTest');
    load(modelPath, 'netTrained');

    inputSize = netTrained.Layers(1).InputSize;
    augImdsTest = augmentedImageDatastore(inputSize, imdsTest);

    predictedLabels = classify(netTrained, augImdsTest);
    trueLabels = imdsTest.Labels;

    % Confusion matrix heatmap
    confMat = confusionmat(trueLabels, predictedLabels);
    % Calculate performance metrics
    TP = confMat(2,2); % True Positives
    TN = confMat(1,1); % True Negatives
    FP = confMat(1,2); % False Positives
    FN = confMat(2,1); % False Negatives
    
    accuracy = (TP + TN) / sum(confMat(:));
    precision = TP / (TP + FP);
    recall = TP / (TP + FN);
    f1_score = 2 * (precision * recall) / (precision + recall);

    metricsText = {
        sprintf('Accuracy: %.2f%% - Overall correct predictions', accuracy * 100)
        sprintf('Precision: %.2f%% - Correct positive predictions', precision * 100)
        sprintf('Recall: %.2f%% - Correctly identified positives', recall * 100)
        sprintf('F1 Score: %.2f%% - Harmonic mean of precision & recall', f1_score * 100)
    };
    
    app.AccuracysStatusTextArea.Value = metricsText;

    h = heatmap(app.HeatmapPanel, confMat, ...
        'Title', 'Confusion Matrix', ...
        'XLabel', 'Predicted', ...
        'YLabel', 'Actual', ...
        'ColorbarVisible', 'on');
    colormap(h, copper);

    classDescriptions = containers.Map(...
        {'0', '1', '2', '3'}, ...
        {'Benign', 'Malignant', 'In-Situ', 'Normal'});

   % Store the current model's metrics
    metrics = [accuracy, precision, recall, f1_score] * 100;
    app.ModelMetricMap(modelName) = metrics;
    
    % Get all models and their metrics
    modelNames = keys(app.ModelMetricMap);
    numModels = numel(modelNames);
    allMetrics = zeros(numModels, 4); % Each row = model, columns = metrics
    
    for i = 1:numModels
        allMetrics(i, :) = app.ModelMetricMap(modelNames{i});
    end
    
    % Plot grouped bar chart
    cla(app.MetricAxes); % Clear the axes
    b = bar(app.MetricAxes, allMetrics', 'grouped'); % Transpose for grouped bars by metric
    
    xticklabels(app.MetricAxes, {'Accuracy', 'Precision', 'Recall', 'F1 Score'});
    ylabel(app.MetricAxes, 'Percentage (%)');
    ylim(app.MetricAxes, [0 100]);
    title(app.MetricAxes, 'Model Performance Metrics');
    legend(app.MetricAxes, modelNames, 'Location', 'southoutside');
    grid(app.MetricAxes, 'on');

    % Misclassified samples
    misIdx = find(predictedLabels ~= trueLabels);
    
    numSamples = min(15, numel(misIdx));
    
    % Clear previous images from the grid
    delete(app.GridLayout.Children);
    
    for i = 1:numSamples
        % Create a nested grid layout for each cell
        nestedGrid = uigridlayout(app.GridLayout, [2, 1]); % 2 rows: label + image
        nestedGrid.RowHeight = {'fit', '4x'};  % Label fits, image takes the rest
        nestedGrid.ColumnWidth = {'1x'};       % Single column
        
        % Add label
        lbl = uilabel(nestedGrid);
        trueClass = string(trueLabels(misIdx(i)));
        predClass = string(predictedLabels(misIdx(i)));
        trueDesc = classDescriptions(trueClass);
        predDesc = classDescriptions(predClass);
        lbl.Text = sprintf('True: %s (%s) | Pred: %s (%s)', ...
            trueClass, trueDesc, predClass, predDesc);
        lbl.HorizontalAlignment = 'center';
        lbl.FontSize = 10;
    
        imgComponent = uiimage(nestedGrid);
        img = readimage(imdsTest, misIdx(i));
        img = imresize(img, 3);  % Optional scaling if you want bigger images
        imgComponent.ImageSource = img;
        imgComponent.Layout.Row = 2;  % Place it in the second row (below label)
        imgComponent.Layout.Column = 1;
        imgComponent.ScaleMethod = 'stretch';  % Fill the nested grid cell
        
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetricAxes = uiaxes(app.UIFigure);
            title(app.MetricAxes, 'Model Performance Metrics')
            ylabel(app.MetricAxes, 'Percentage (%)')
            zlabel(app.MetricAxes, 'Z')
            colormap(app.MetricAxes, 'spring')
            app.AIModelNetworkLabel = uilabel(app.UIFigure);
            app.AIModelNetworkLabel.Text = 'AI Model Network ';
            app.AIModelNetworkDropDown = uidropdown(app.UIFigure);
            app.AIModelNetworkDropDown.Items = {'Select', 'VGG-19', 'InceptionNet', 'AlexNet', 'ResNet-50', 'MobileNetV2', 'GoogLeNet'};
            app.AIModelNetworkDropDown.Value = 'Select';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AccuracysStatusTextAreaLabel = uilabel(app.UIFigure);
            app.AccuracysStatusTextAreaLabel.Text = 'Accuracys Status';
            app.AccuracysStatusTextArea = uitextarea(app.UIFigure);
            app.AdvancingBreastCancerDiagnosisAIModelEvaluationDashboardLabel = uilabel(app.UIFigure);
            app.AdvancingBreastCancerDiagnosisAIModelEvaluationDashboardLabel.Text = 'Advancing Breast Cancer Diagnosis: AI Model Evaluation Dashboard';
            app.MisclassifiedPanel = uipanel(app.UIFigure);
            app.MisclassifiedPanel.Title = 'Misclassified Panel';
            app.GridLayout = uigridlayout(app.MisclassifiedPanel);
            app.HeatmapPanel = uipanel(app.UIFigure);
            app.HeatmapPanel.Title = 'Confusion Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end